classdef MarkEventsVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ZoomButton                 matlab.ui.control.StateButton
        Label_other                matlab.ui.control.Label
        Label_RFS                  matlab.ui.control.Label
        Label_RFO                  matlab.ui.control.Label
        Label_LFS                  matlab.ui.control.Label
        Label_LFO                  matlab.ui.control.Label
        JumptoFrameEditField       matlab.ui.control.NumericEditField
        JumptoFrameEditFieldLabel  matlab.ui.control.Label
        CorrectButton_Other        matlab.ui.control.Button
        CorrectButton_RFS          matlab.ui.control.Button
        CorrectButton_RFO          matlab.ui.control.Button
        CorrectButton_LFS          matlab.ui.control.Button
        CorrectButton_LFO          matlab.ui.control.Button
        MarkEventsButton           matlab.ui.control.StateButton
        SaveEventsButton           matlab.ui.control.Button
        Instructionsforframecontrol112131041051006100Label  matlab.ui.control.Label
        PlayButton                 matlab.ui.control.StateButton
        RFSPress4TextArea          matlab.ui.control.TextArea
        RFSPress4TextAreaLabel     matlab.ui.control.Label
        OTHERPress5TextArea        matlab.ui.control.TextArea
        OTHERPress5TextAreaLabel   matlab.ui.control.Label
        RFOPress3TextArea          matlab.ui.control.TextArea
        RFOPress3TextAreaLabel     matlab.ui.control.Label
        LFSPress2TextArea          matlab.ui.control.TextArea
        LFSPress2TextAreaLabel     matlab.ui.control.Label
        LFOPress1TextArea          matlab.ui.control.TextArea
        LFOPress1TextAreaLabel     matlab.ui.control.Label
        LoadVideosButton           matlab.ui.control.Button
        UIAxes_Vicon               matlab.ui.control.UIAxes
        UIAxes_VideoR              matlab.ui.control.UIAxes
        UIAxes_VideoL              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vidObj1 % Description
        vidObj2
        PathSession % Description
        PathProcessed
        N_frames
        N
        brightLev
        filename
        eventMarked
        i
        fr
        start_frame % Description
        cnt_frame
        video1
        video2
        pick_index % Description
        pickPosition % Description
        simiMat % Description
        h_cntFrame % Description
        vidFrameRate % Description
        kinFrameRate % Description
    end
    
    methods (Access = private)
        
        
        function update_image(app)
            if app.cnt_frame > app.i*app.N
                app.i = app.i + 1;
                disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 1'])
                app.video1 = read(app.vidObj1, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
                
                disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 2'])
                app.video2 = read(app.vidObj2, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            elseif app.cnt_frame <= (app.i-1)*app.N
                app.i = app.i - 1;
                disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 1'])
                app.video1 = read(app.vidObj1, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
                
                disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 2'])
                app.video2 = read(app.vidObj2, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            end
            app.fr = app.cnt_frame - (app.i-1)*app.N;
            
            imshow(app.video1(:,:,:,app.fr)*app.brightLev,'Parent',app.UIAxes_VideoL);
            app.UIAxes_VideoL.Title.String = ['Left - frame ' num2str(app.cnt_frame) ' / ' num2str(app.N_frames)];
            
            imshow(app.video2(:,:,:,app.fr)*app.brightLev,'Parent',app.UIAxes_VideoR);
            app.UIAxes_VideoR.Title.String = ['Right - frame ' num2str(app.cnt_frame) ' / ' num2str(app.N_frames)];
            
            set(app.h_cntFrame,'XData',app.cnt_frame*[1 1])
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
            
            disp('Please Load the videos')
            app.start_frame = 1;
            app.cnt_frame = app.start_frame;
            app.pick_index = 0;
        end

        % Button pushed function: LoadVideosButton
        function LoadVideosButtonPushed(app, event)
            % Read video files
            [filenm,path,FilterIndex] = uigetfile('*','Select the 1st video file');
            if FilterIndex == 0
                disp('Error in the video file loading')
                return
            else
                disp('1st video file has been successfully loaded')
            end
            cd(path)
            app.vidObj1 = VideoReader(filenm);
            app.vidFrameRate = app.vidObj1.FrameRate;
            
            [filenm,path,FilterIndex] = uigetfile('*','Select the 2nd video file');
            if FilterIndex == 0
                disp('Error in the video file loading')
                return
            else
                disp('2nd video file has been successfully loaded')
            end
            app.vidObj2 = VideoReader(filenm);
            
            [filenm,path,FilterIndex] = uigetfile('*','Select the simi matlab file');
            if FilterIndex == 0
                disp('Error in the video file loading')
                return
            else
                disp('simi matlab file has been successfully loaded')
            end
            app.simiMat = load(filenm);
            
            % Path Info
            slashLoc = find(path == filesep);
            app.PathSession = path(1:slashLoc(end-1)); % Path to todays session
            app.PathProcessed = [path 'ProcessedData' filesep];
            if ~exist(app.PathProcessed,'dir')
                mkdir(app.PathProcessed);
            end
            
            app.N_frames = floor(app.vidObj1.Duration*app.vidObj1.FrameRate);
            if isunix % On Mac it's suuuuper slow to load all frames
                app.N = 500; %N_frames;   % Nb of frames loaded at once
            else % But not on Windows
                app.N =  500;
                %          N =  N_frames;
                % N = 3000;
            end
            app.brightLev = 1;
            app.filename = filenm(1:end-4);
            
            % Display the first frame images
            app.i = 1;   % ceil rounds N_frames/N to the nearest integer greater than or equal to that element.
            app.fr = 1;
            
            
            disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 1'])
            app.video1 = read(app.vidObj1, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            
%             axes(app.UIAxes_VideoL)
            imshow(app.video1(:,:,:,app.fr)*app.brightLev,'Parent',app.UIAxes_VideoL);
            app.UIAxes_VideoL.Title.String = ['Left - frame ' num2str(app.fr)];
            
            disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 2'])
            app.video2 = read(app.vidObj2, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            
%             axes(app.UIAxes_VideoR)
            imshow(app.video2(:,:,:,app.fr)*app.brightLev,'Parent',app.UIAxes_VideoR);
            app.UIAxes_VideoR.Title.String = ['Right - frame ' num2str(app.fr)];
            
            % plot WRB signal and robot signal
            simiMat = app.simiMat;
            dataRfoot = simiMat.KIN_3D.rightFifthMetatarsalMT.z;
            dataLfoot = simiMat.KIN_3D.leftFifthMetatarsalMT.z;
            app.kinFrameRate = 100; % simi framerate
            
            hold(app.UIAxes_Vicon,'on')
            plot(app.UIAxes_Vicon,[1:length(dataLfoot)]/app.kinFrameRate*app.vidFrameRate,dataLfoot,'-b')
            plot(app.UIAxes_Vicon,[1:length(dataRfoot)]/app.kinFrameRate*app.vidFrameRate,dataRfoot,'-r')
            app.h_cntFrame = plot(app.UIAxes_Vicon,app.cnt_frame*[1 1],[0 0.15],':k');
            set(app.UIAxes_Vicon,'ylim',[0 0.15])
            
            
            % Reset or load events
            backupEvent = [app.PathProcessed app.filename '_events.mat'];
            if exist(backupEvent,'file') ~= 0
                choice = questdlg('An events file with the same name already exists, what do you want to do?', ...
                    'There is already an file for this acquisition', ...
                    'Start from scratch','Start with those previous events','Start from scratch');
                % Handle response
                switch choice
                    case 'Start with those previous events'
                        load(backupEvent);
                        disp('Previous events have been loaded')
                        app.eventMarked.LFO = dataEvent.LFO;
                        app.eventMarked.LFS = dataEvent.LFS;
                        app.eventMarked.RFO = dataEvent.RFO;
                        app.eventMarked.OTHER = dataEvent.other;
                        app.eventMarked.RFS = dataEvent.RFS;
                    case 'Start from scratch'
                        app.eventMarked.LFO = [];   %beginningOfMovement
                        app.eventMarked.LFS = [];   %Grasping
                        app.eventMarked.RFO = [];   %LightOff ot the time Mk leave the object
                        app.eventMarked.OTHER = [];   %is it a successful trial
                        app.eventMarked.RFS = [];
                end
            else
                app.eventMarked.LFO = [];   %beginningOfMovement
                app.eventMarked.LFS = [];   %Grasping
                app.eventMarked.RFO = [];   %LightOff ot the time Mk leave the object
                app.eventMarked.OTHER = [];   %is it a successful trial
                app.eventMarked.RFS = [];
            end
            
            app.LFOPress1TextArea.Value = num2str(app.eventMarked.LFO);
            app.Label_LFO.Text = ['(' num2str(length(app.eventMarked.LFO)) ')'];

            app.LFSPress2TextArea.Value = num2str(app.eventMarked.LFS);
            app.Label_LFS.Text = ['(' num2str(length(app.eventMarked.LFS)) ')'];

            app.RFOPress3TextArea.Value = num2str(app.eventMarked.RFO);
            app.Label_RFO.Text = ['(' num2str(length(app.eventMarked.RFO)) ')'];
            
            app.OTHERPress5TextArea.Value = num2str(app.eventMarked.OTHER);
            app.Label_other.Text = ['(' num2str(length(app.eventMarked.OTHER)) ')'];
         
            app.RFSPress4TextArea.Value = num2str(app.eventMarked.RFS);
            app.Label_RFS.Text = ['(' num2str(length(app.eventMarked.RFS)) ')'];
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value == 1
                disp('Start to play the videos, press to stop')
                app.PlayButton.Text = 'STOP Play';
                while value && app.cnt_frame<=app.N_frames
                    app.cnt_frame = app.cnt_frame+1;
                    app.update_image;
                    
                    value = app.PlayButton.Value;
                    pause(0.5)
                end
            else
                disp('Stopped the videos, press to start')
                app.PlayButton.Text = 'Play';
            end
        end

        % Value changed function: MarkEventsButton
        function MarkEventsButtonValueChanged(app, event)
            value = app.MarkEventsButton.Value;
            
            if value
                disp('Start changing image frame with keyboard')
                
            else
                disp('Stop changing image frame with keyboard')
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.MarkEventsButton.Value
                switch key
                    case 'leftarrow'
                        app.cnt_frame = app.cnt_frame - 1;
                    case 'rightarrow'
                        app.cnt_frame = app.cnt_frame + 1;
                    case 'downarrow'
                        app.cnt_frame = app.cnt_frame - 10;
                    case 'uparrow'
                        app.cnt_frame = app.cnt_frame + 10;
                    case '9'
                        app.cnt_frame = app.cnt_frame + 100;
                    case '0'
                        app.cnt_frame = app.cnt_frame - 100;
                        
                    case '1'
                        app.eventMarked.LFO = [app.eventMarked.LFO app.cnt_frame];
                        app.eventMarked.LFO = sort(app.eventMarked.LFO,'ascend');
                        app.LFOPress1TextArea.Value = num2str(app.eventMarked.LFO);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_LFO.Text = ['(' num2str(length(app.eventMarked.LFO)) ')'];
                    case '2'
                        app.eventMarked.LFS = [app.eventMarked.LFS app.cnt_frame];
                        app.eventMarked.LFS = sort(app.eventMarked.LFS,'ascend');
                        app.LFSPress2TextArea.Value = num2str(app.eventMarked.LFS);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_LFS.Text = ['(' num2str(length(app.eventMarked.LFS)) ')'];
                    case '3'
                        app.eventMarked.RFO = [app.eventMarked.RFO app.cnt_frame];
                        app.eventMarked.RFO = sort(app.eventMarked.RFO,'ascend');
                        app.RFOPress3TextArea.Value = num2str(app.eventMarked.RFO);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_RFO.Text = ['(' num2str(length(app.eventMarked.RFO)) ')'];
                    case '4'
                        app.eventMarked.RFS = [app.eventMarked.RFS app.cnt_frame];
                        app.eventMarked.RFS = sort(app.eventMarked.RFS,'ascend');
                        app.RFSPress4TextArea.Value = num2str(app.eventMarked.RFS);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_RFS.Text = ['(' num2str(length(app.eventMarked.RFS)) ')'];
                    case '5'
                        app.eventMarked.OTHER = [app.eventMarked.OTHER app.cnt_frame];
                        app.eventMarked.OTHER = sort(app.eventMarked.OTHER,'ascend');
                        app.OTHERPress5TextArea.Value = num2str(app.eventMarked.OTHER);
                        disp([key ' - current frame:' num2str(app.cnt_frame)])

                        app.Label_other.Text = ['(' num2str(length(app.eventMarked.OTHER)) ')'];
                        
                        
                end
                app.cnt_frame = max(app.cnt_frame,1);
                app.cnt_frame = min(app.cnt_frame,app.N_frames);
                
                app.update_image;
            end
        end

        % Button pushed function: SaveEventsButton
        function SaveEventsButtonPushed(app, event)
            dataEvent.LFO=app.eventMarked.LFO;
            dataEvent.LFS=app.eventMarked.LFS;
            dataEvent.RFO=app.eventMarked.RFO;
            dataEvent.RFS = app.eventMarked.RFS;
            dataEvent.other=app.eventMarked.OTHER;
            
            saveEvent = [app.PathProcessed app.filename '_events.mat'];
            
            save(saveEvent,'dataEvent')
            disp([saveEvent ' is saved'])
        end

        % Button pushed function: CorrectButton_LFO
        function CorrectButton_LFOPushed(app, event)
           
            cnt_index = find(app.eventMarked.LFO==app.cnt_frame);
            app.eventMarked.LFO(cnt_index) = [];
            app.LFOPress1TextArea.Value = num2str(app.eventMarked.LFO);
            disp('LFO is corrected')

            app.Label_LFO.Text = ['(' num2str(length(app.eventMarked.LFO)) ')'];
        end

        % Button pushed function: CorrectButton_LFS
        function CorrectButton_LFSPushed(app, event)
           
            cnt_index = find(app.eventMarked.LFS==app.cnt_frame);
            app.eventMarked.LFS(cnt_index) = [];
            app.LFSPress2TextArea.Value = num2str(app.eventMarked.LFS);
            disp('LFS is corrected')

            app.Label_LFS.Text = ['(' num2str(length(app.eventMarked.LFS)) ')'];
        end

        % Button pushed function: CorrectButton_RFO
        function CorrectButton_RFOPushed(app, event)
            
            cnt_index = find(app.eventMarked.RFO==app.cnt_frame);
            app.eventMarked.RFO(cnt_index) = [];
            app.RFOPress3TextArea.Value = num2str(app.eventMarked.RFO);
            disp('RFO is corrected')

            app.Label_RFO.Text = ['(' num2str(length(app.eventMarked.RFO)) ')'];
        end

        % Button pushed function: CorrectButton_RFS
        function CorrectButton_RFSPushed(app, event)
            
            cnt_index = find(app.eventMarked.RFS(:,2)==app.cnt_frame);
            app.eventMarked.RFS(cnt_index,:) = [];
            app.RFSPress4TextArea.Value = num2str(app.eventMarked.RFS');

            disp('RFS is corrected')

            app.Label_RFS.Text = ['(' num2str(length(app.eventMarked.RFS)) ')'];
        end

        % Button pushed function: CorrectButton_Other
        function CorrectButton_OtherPushed(app, event)
            
            cnt_index = find(app.eventMarked.OTHER(:,1)==app.cnt_frame);
            app.eventMarked.OTHER(cnt_index,:) = [];
            app.OTHERPress5TextArea.Value = num2str(app.eventMarked.OTHER');
            disp('OTHER is corrected')

            app.Label_other.Text = ['(' num2str(length(app.eventMarked.OTHER)) ')'];
        end

        % Value changed function: JumptoFrameEditField
        function JumptoFrameEditFieldValueChanged(app, event)
            value = app.JumptoFrameEditField.Value;
            app.cnt_frame = value;
            app.i = ceil(app.cnt_frame/app.N);
            
            disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 1'])
            app.video1 = read(app.vidObj1, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            
            disp(['Loading frames ', num2str(1+(app.i-1)*app.N), ' to ', num2str(app.i*app.N), ' for video 2'])
            app.video2 = read(app.vidObj2, [1+(app.i-1)*app.N min(app.i*app.N, app.N_frames)]);
            app.update_image;
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            if value
                set(app.UIAxes_Vicon,'ylim',[150 400],'xlim',app.cnt_frame+[-10 10]*app.vidFrameRate)
            else
                set(app.UIAxes_Vicon,'ylim',[150 400],'xlim',[1 app.N_frames])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes_VideoL = uiaxes(app.UIFigure);
            title(app.UIAxes_VideoL, '2nd Video')
            app.UIAxes_VideoL.XTick = [];
            app.UIAxes_VideoL.YTick = [];
            app.UIAxes_VideoR = uiaxes(app.UIFigure);
            title(app.UIAxes_VideoR, '1st Video')
            app.UIAxes_VideoR.XTick = [];
            app.UIAxes_VideoR.YTick = [];
            app.UIAxes_Vicon = uiaxes(app.UIFigure);
            app.UIAxes_Vicon.YTick = [];
            app.LoadVideosButton = uibutton(app.UIFigure, 'push');
            app.LoadVideosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideosButtonPushed, true);
            app.LoadVideosButton.Text = 'Load Videos';
            app.LFOPress1TextAreaLabel = uilabel(app.UIFigure);
            app.LFOPress1TextAreaLabel.Text = 'LFO, Press ''1''';
            app.LFOPress1TextArea = uitextarea(app.UIFigure);
            app.LFSPress2TextAreaLabel = uilabel(app.UIFigure);
            app.LFSPress2TextAreaLabel.Text = 'LFS, Press ''2';
            app.LFSPress2TextArea = uitextarea(app.UIFigure);
            app.RFOPress3TextAreaLabel = uilabel(app.UIFigure);
            app.RFOPress3TextAreaLabel.Text = '''RFO, Press 3''';
            app.RFOPress3TextArea = uitextarea(app.UIFigure);
            app.OTHERPress5TextAreaLabel = uilabel(app.UIFigure);
            app.OTHERPress5TextAreaLabel.Text = '''OTHER, Press 5''';
            app.OTHERPress5TextArea = uitextarea(app.UIFigure);
            app.RFSPress4TextAreaLabel = uilabel(app.UIFigure);
            app.RFSPress4TextAreaLabel.Text = 'RFS, Press ''4''';
            app.RFSPress4TextArea = uitextarea(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.Instructionsforframecontrol112131041051006100Label = uilabel(app.UIFigure);
            app.Instructionsforframecontrol112131041051006100Label.Text = {'Instructions for frame control: rightarrow: +1,  leftarrow: -1,  '; 'uparrow: +10, downarrow: -10,  9:+100,  0:-100 '};
            app.SaveEventsButton = uibutton(app.UIFigure, 'push');
            app.SaveEventsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEventsButtonPushed, true);
            app.SaveEventsButton.Text = 'Save Events';
            app.MarkEventsButton = uibutton(app.UIFigure, 'state');
            app.MarkEventsButton.ValueChangedFcn = createCallbackFcn(app, @MarkEventsButtonValueChanged, true);
            app.MarkEventsButton.Text = 'Mark Events';
            app.CorrectButton_LFO = uibutton(app.UIFigure, 'push');
            app.CorrectButton_LFO.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_LFOPushed, true);
            app.CorrectButton_LFO.Text = 'Correct ';
            app.CorrectButton_LFS = uibutton(app.UIFigure, 'push');
            app.CorrectButton_LFS.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_LFSPushed, true);
            app.CorrectButton_LFS.Text = 'Correct ';
            app.CorrectButton_RFO = uibutton(app.UIFigure, 'push');
            app.CorrectButton_RFO.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_RFOPushed, true);
            app.CorrectButton_RFO.Text = 'Correct ';
            app.CorrectButton_RFS = uibutton(app.UIFigure, 'push');
            app.CorrectButton_RFS.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_RFSPushed, true);
            app.CorrectButton_RFS.Text = 'Correct ';
            app.CorrectButton_Other = uibutton(app.UIFigure, 'push');
            app.CorrectButton_Other.ButtonPushedFcn = createCallbackFcn(app, @CorrectButton_OtherPushed, true);
            app.CorrectButton_Other.Text = 'Correct ';
            app.JumptoFrameEditFieldLabel = uilabel(app.UIFigure);
            app.JumptoFrameEditFieldLabel.Text = 'Jump to Frame';
            app.JumptoFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.JumptoFrameEditField.ValueChangedFcn = createCallbackFcn(app, @JumptoFrameEditFieldValueChanged, true);
            app.JumptoFrameEditField.Value = 1;
            app.Label_LFO = uilabel(app.UIFigure);
            app.Label_LFO.Text = '(0)';
            app.Label_LFS = uilabel(app.UIFigure);
            app.Label_LFS.Text = '(0)';
            app.Label_RFO = uilabel(app.UIFigure);
            app.Label_RFO.Text = '(0)';
            app.Label_RFS = uilabel(app.UIFigure);
            app.Label_RFS.Text = '(0)';
            app.Label_other = uilabel(app.UIFigure);
            app.Label_other.Text = '(0)';
            app.ZoomButton = uibutton(app.UIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MarkEventsVideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end